classdef enterMicroscopyImg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectButton                 matlab.ui.control.Button
        OutputpathEditField          matlab.ui.control.EditField
        OutputpathEditFieldLabel     matlab.ui.control.Label
        Color3Panel                  matlab.ui.container.Panel
        PresentCheckBox_2            matlab.ui.control.CheckBox
        FluorophoreEditField_3       matlab.ui.control.EditField
        FluorophoreEditField_3Label  matlab.ui.control.Label
        ChannelnrEditField_3         matlab.ui.control.NumericEditField
        ChannelnrEditField_3Label    matlab.ui.control.Label
        StainEditField_3             matlab.ui.control.EditField
        StainEditField_3Label        matlab.ui.control.Label
        ColorDropDown_3              matlab.ui.control.DropDown
        ColorDropDown_3Label         matlab.ui.control.Label
        ChannelsPanel                matlab.ui.container.Panel
        Color2Panel                  matlab.ui.container.Panel
        PresentCheckBox              matlab.ui.control.CheckBox
        FluorophoreEditField_2       matlab.ui.control.EditField
        FluorophoreEditField_2Label  matlab.ui.control.Label
        ChannelnrEditField_2         matlab.ui.control.NumericEditField
        ChannelnrEditField_2Label    matlab.ui.control.Label
        StainEditField_2             matlab.ui.control.EditField
        StainEditField_2Label        matlab.ui.control.Label
        ColorDropDown_2              matlab.ui.control.DropDown
        ColorDropDown_2Label         matlab.ui.control.Label
        Color1Panel                  matlab.ui.container.Panel
        FluorophoreEditField         matlab.ui.control.EditField
        FluorophoreEditFieldLabel    matlab.ui.control.Label
        ChannelnrEditField           matlab.ui.control.NumericEditField
        ChannelnrEditFieldLabel      matlab.ui.control.Label
        StainEditField               matlab.ui.control.EditField
        StainEditFieldLabel          matlab.ui.control.Label
        ColorDropDown                matlab.ui.control.DropDown
        ColorDropDownLabel           matlab.ui.control.Label
        FluorescenceCheckBox         matlab.ui.control.CheckBox
        AreasPanel                   matlab.ui.container.Panel
        OthersomatosensoryCheckBox   matlab.ui.control.CheckBox
        OthervisualareasCheckBox     matlab.ui.control.CheckBox
        OtherthalamusCheckBox        matlab.ui.control.CheckBox
        S1CheckBox                   matlab.ui.control.CheckBox
        LGNCheckBox                  matlab.ui.control.CheckBox
        PSSCheckBox                  matlab.ui.control.CheckBox
        Area17CheckBox               matlab.ui.control.CheckBox
        LImg_2                       matlab.ui.control.Label
        BasicinfoPanel               matlab.ui.container.Panel
        OrientationDropDown          matlab.ui.control.DropDown
        OrientationDropDownLabel     matlab.ui.control.Label
        HemisphereDropDown           matlab.ui.control.DropDown
        HemisphereDropDownLabel      matlab.ui.control.Label
        SectioncolumnEditField       matlab.ui.control.NumericEditField
        SectioncolumnEditFieldLabel  matlab.ui.control.Label
        SectionrowEditField          matlab.ui.control.NumericEditField
        SectionrowEditFieldLabel     matlab.ui.control.Label
        SlideEditField               matlab.ui.control.NumericEditField
        SlideEditFieldLabel          matlab.ui.control.Label
        MagnificationEditField       matlab.ui.control.NumericEditField
        MagnificationEditFieldLabel  matlab.ui.control.Label
        EScale                       matlab.ui.control.EditField
        ScalebarpxumEditFieldLabel   matlab.ui.control.Label
        AcqdateDatePicker            matlab.ui.control.DatePicker
        AcqdateDatePickerLabel       matlab.ui.control.Label
        DMicroscope                  matlab.ui.control.DropDown
        MicroscopeDropDownLabel      matlab.ui.control.Label
        EExpID                       matlab.ui.control.EditField
        ExpIDLabel                   matlab.ui.control.Label
        BImg                         matlab.ui.control.Button
        LImg                         matlab.ui.control.Label
        OutputimageLabel             matlab.ui.control.Label
        BEnter                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        connAccess % connection to Access database
        connSQL    % connection to mySQL database % Description
    end
    
    methods (Access = private)
        
        function openAccess(app)
            app.connAccess=database('ferretDatabase','','');
            if ~isempty(app.connAccess.Message)
                uialert(app.UIDBEntry,'Cannot open Access Database!','Error')
                return;
            end
        end

        function openSQL(app)
            app.connSQL=database('mscopeDatabase','','');
            if ~isempty(app.connSQL.Message)
                uialert(app.UIDBEntry,'Cannot open MySQL Database!','Error')
                return;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BEnter
        function BEnterButtonPushed(app, event)
            %open databases
            app.openAccess();
            app.openSQL();


            expId=app.EExpID.Value;

            %test whether info still needs to be added to animal table
            sqlquery=['SELECT animalid FROM tblanimal WHERE expId=''',expId,''''];
            data=select(app.connSQL,sqlquery);
            
            if isempty(data) %need to get data from access

                %first get eartag (providing experiment here)
                sqlquery=['SELECT Eartag FROM tblTissueInfo WHERE [Experiment ID]=''', expId, ''''];

                data=select(app.connAccess,sqlquery);
                if isempty(data)
                    uialert(app.UIDBEntry,'Exp ID does not exist!','Error');
                    return;
                end

                eartag=data.Eartag{1};

                %now get rest of base info
                sqlquery=['SELECT Sex, DOB, Disposed FROM tblBaseInfo WHERE Eartag=''', eartag, ''''];
                data=select(app.connAccess,sqlquery);

                tableOut=table;
                tableOut.expId={expId};
                tableOut.sex=data.Sex;
                age=between(datetime(data.DOB),datetime(data.Disposed),'days');
                tableOut.age={caldays(age)};
                tableOut.eartag={eartag};

                %rearing
                sqlquery=['SELECT DR,LE,RE FROM tblRearingInfo WHERE Eartag=''', eartag, ''''];
                data=select(app.connAccess,sqlquery);

                if ~isempty(data)
                    if data.DR==1
                        tableOut.rearing={'DR'};
                    elseif data.LE==1 && data.RE==1
                        tableOut.rearing={'BLS'};
                    elseif data.LE==1 && data.RE==0
                        tableOut.rearing={'LLS'};
                    elseif data.LE==0 && data.RE==1
                        tableOut.rearing={'RLS'};
                    end
                else
                    tableOut.rearing={'normal'};
                end

                %write into database
                sqlwrite(app.connSQL,'tblanimal',tableOut);
            end

            %enter into microscopy data base
            %get animal id
            sqlquery=['SELECT animalid FROM tblanimal WHERE expId=''',expId,''''];
            data=select(app.connSQL,sqlquery);
            
            tableOut=table;
            tableOut.animalid=data.animalid;
            tableOut.microscope={app.DMicroscope.Value};
            %tableOut.magnification={app.DObjective.Value};
            tableOut.filename={'test'};

            disp(tableOut)

            sqlwrite(app.connSQL,'tblmain',tableOut);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BEnter = uibutton(app.UIFigure, 'push');
            app.BEnter.ButtonPushedFcn = createCallbackFcn(app, @BEnterButtonPushed, true);
            app.BEnter.Text = 'Enter';
            app.OutputimageLabel = uilabel(app.UIFigure);
            app.OutputimageLabel.Text = 'Output image';
            app.LImg = uilabel(app.UIFigure);
            app.LImg.Text = '';
            app.BImg = uibutton(app.UIFigure, 'push');
            app.BImg.Text = 'Select image';
            app.BasicinfoPanel = uipanel(app.UIFigure);
            app.BasicinfoPanel.Title = 'Basic info';
            app.ExpIDLabel = uilabel(app.BasicinfoPanel);
            app.ExpIDLabel.Text = 'Exp ID';
            app.EExpID = uieditfield(app.BasicinfoPanel, 'text');
            app.MicroscopeDropDownLabel = uilabel(app.BasicinfoPanel);
            app.MicroscopeDropDownLabel.Text = 'Microscope';
            app.DMicroscope = uidropdown(app.BasicinfoPanel);
            app.DMicroscope.Items = {'Keyence', 'Lab Zeiss', 'Zeiss Confocal'};
            app.DMicroscope.Value = 'Keyence';
            app.AcqdateDatePickerLabel = uilabel(app.BasicinfoPanel);
            app.AcqdateDatePickerLabel.Text = 'Acq date';
            app.AcqdateDatePicker = uidatepicker(app.BasicinfoPanel);
            app.ScalebarpxumEditFieldLabel = uilabel(app.BasicinfoPanel);
            app.ScalebarpxumEditFieldLabel.Text = 'Scale bar (px/um)';
            app.EScale = uieditfield(app.BasicinfoPanel, 'text');
            app.MagnificationEditFieldLabel = uilabel(app.BasicinfoPanel);
            app.MagnificationEditFieldLabel.Text = 'Magnification';
            app.MagnificationEditField = uieditfield(app.BasicinfoPanel, 'numeric');
            app.MagnificationEditField.Limits = [1 Inf];
            app.MagnificationEditField.Value = 10;
            app.SlideEditFieldLabel = uilabel(app.BasicinfoPanel);
            app.SlideEditFieldLabel.Text = 'Slide';
            app.SlideEditField = uieditfield(app.BasicinfoPanel, 'numeric');
            app.SlideEditField.Limits = [1 Inf];
            app.SlideEditField.Value = 1;
            app.SectionrowEditFieldLabel = uilabel(app.BasicinfoPanel);
            app.SectionrowEditFieldLabel.Text = 'Section row';
            app.SectionrowEditField = uieditfield(app.BasicinfoPanel, 'numeric');
            app.SectionrowEditField.Limits = [1 Inf];
            app.SectionrowEditField.Value = 1;
            app.SectioncolumnEditFieldLabel = uilabel(app.BasicinfoPanel);
            app.SectioncolumnEditFieldLabel.Text = 'Section column';
            app.SectioncolumnEditField = uieditfield(app.BasicinfoPanel, 'numeric');
            app.SectioncolumnEditField.Limits = [1 Inf];
            app.SectioncolumnEditField.Value = 1;
            app.HemisphereDropDownLabel = uilabel(app.BasicinfoPanel);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.BasicinfoPanel);
            app.HemisphereDropDown.Items = {'L', 'R'};
            app.HemisphereDropDown.Value = 'L';
            app.OrientationDropDownLabel = uilabel(app.BasicinfoPanel);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.BasicinfoPanel);
            app.OrientationDropDown.Items = {'Sagittal', 'Coronal', 'Horizonal', 'Parallel dura'};
            app.OrientationDropDown.Value = 'Sagittal';
            app.LImg_2 = uilabel(app.UIFigure);
            app.LImg_2.Text = '';
            app.AreasPanel = uipanel(app.UIFigure);
            app.AreasPanel.Title = 'Areas';
            app.Area17CheckBox = uicheckbox(app.AreasPanel);
            app.Area17CheckBox.Text = 'Area 17';
            app.PSSCheckBox = uicheckbox(app.AreasPanel);
            app.PSSCheckBox.Text = 'PSS';
            app.LGNCheckBox = uicheckbox(app.AreasPanel);
            app.LGNCheckBox.Text = 'LGN';
            app.S1CheckBox = uicheckbox(app.AreasPanel);
            app.S1CheckBox.Text = 'S1';
            app.OtherthalamusCheckBox = uicheckbox(app.AreasPanel);
            app.OtherthalamusCheckBox.Text = 'Other thalamus';
            app.OthervisualareasCheckBox = uicheckbox(app.AreasPanel);
            app.OthervisualareasCheckBox.Text = 'Other visual areas';
            app.OthersomatosensoryCheckBox = uicheckbox(app.AreasPanel);
            app.OthersomatosensoryCheckBox.Text = 'Other somatosensory';
            app.ChannelsPanel = uipanel(app.UIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.FluorescenceCheckBox = uicheckbox(app.ChannelsPanel);
            app.FluorescenceCheckBox.Text = 'Fluorescence?';
            app.Color1Panel = uipanel(app.ChannelsPanel);
            app.Color1Panel.Title = 'Color 1';
            app.ColorDropDownLabel = uilabel(app.Color1Panel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.Color1Panel);
            app.ColorDropDown.Items = {'black', 'purple', 'brown', 'fl green', 'fl red', 'fl blue', 'fl far-red'};
            app.ColorDropDown.Value = 'black';
            app.StainEditFieldLabel = uilabel(app.Color1Panel);
            app.StainEditFieldLabel.Text = 'Stain';
            app.StainEditField = uieditfield(app.Color1Panel, 'text');
            app.ChannelnrEditFieldLabel = uilabel(app.Color1Panel);
            app.ChannelnrEditFieldLabel.Text = 'Channel nr.';
            app.ChannelnrEditField = uieditfield(app.Color1Panel, 'numeric');
            app.ChannelnrEditField.Limits = [0 Inf];
            app.ChannelnrEditField.Value = 1;
            app.FluorophoreEditFieldLabel = uilabel(app.Color1Panel);
            app.FluorophoreEditFieldLabel.Text = 'Fluorophore';
            app.FluorophoreEditField = uieditfield(app.Color1Panel, 'text');
            app.Color2Panel = uipanel(app.ChannelsPanel);
            app.Color2Panel.Title = 'Color 2';
            app.ColorDropDown_2Label = uilabel(app.Color2Panel);
            app.ColorDropDown_2Label.Text = 'Color';
            app.ColorDropDown_2 = uidropdown(app.Color2Panel);
            app.ColorDropDown_2.Items = {'black', 'purple', 'brown', 'fl green', 'fl red', 'fl blue', 'fl far-red'};
            app.ColorDropDown_2.Value = 'purple';
            app.StainEditField_2Label = uilabel(app.Color2Panel);
            app.StainEditField_2Label.Text = 'Stain';
            app.StainEditField_2 = uieditfield(app.Color2Panel, 'text');
            app.ChannelnrEditField_2Label = uilabel(app.Color2Panel);
            app.ChannelnrEditField_2Label.Text = 'Channel nr.';
            app.ChannelnrEditField_2 = uieditfield(app.Color2Panel, 'numeric');
            app.ChannelnrEditField_2.Limits = [0 Inf];
            app.ChannelnrEditField_2.Value = 2;
            app.FluorophoreEditField_2Label = uilabel(app.Color2Panel);
            app.FluorophoreEditField_2Label.Text = 'Fluorophore';
            app.FluorophoreEditField_2 = uieditfield(app.Color2Panel, 'text');
            app.PresentCheckBox = uicheckbox(app.Color2Panel);
            app.PresentCheckBox.Text = 'Present';
            app.PresentCheckBox.Value = true;
            app.Color3Panel = uipanel(app.ChannelsPanel);
            app.Color3Panel.Title = 'Color 3';
            app.ColorDropDown_3Label = uilabel(app.Color3Panel);
            app.ColorDropDown_3Label.Text = 'Color';
            app.ColorDropDown_3 = uidropdown(app.Color3Panel);
            app.ColorDropDown_3.Items = {'black', 'purple', 'brown', 'fl green', 'fl red', 'fl blue', 'fl far-red'};
            app.ColorDropDown_3.Value = 'brown';
            app.StainEditField_3Label = uilabel(app.Color3Panel);
            app.StainEditField_3Label.Text = 'Stain';
            app.StainEditField_3 = uieditfield(app.Color3Panel, 'text');
            app.ChannelnrEditField_3Label = uilabel(app.Color3Panel);
            app.ChannelnrEditField_3Label.Text = 'Channel nr.';
            app.ChannelnrEditField_3 = uieditfield(app.Color3Panel, 'numeric');
            app.ChannelnrEditField_3.Limits = [0 Inf];
            app.ChannelnrEditField_3.Value = 3;
            app.FluorophoreEditField_3Label = uilabel(app.Color3Panel);
            app.FluorophoreEditField_3Label.Text = 'Fluorophore';
            app.FluorophoreEditField_3 = uieditfield(app.Color3Panel, 'text');
            app.PresentCheckBox_2 = uicheckbox(app.Color3Panel);
            app.PresentCheckBox_2.Text = 'Present';
            app.OutputpathEditFieldLabel = uilabel(app.UIFigure);
            app.OutputpathEditFieldLabel.Text = 'Output path';
            app.OutputpathEditField = uieditfield(app.UIFigure, 'text');
            app.OutputpathEditField.Value = 'z:\microscopy';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.Text = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enterMicroscopyImg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end